classdef vhtp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ParadigmsListBoxLabel          matlab.ui.control.Label
        ParadigmsListBox               matlab.ui.control.ListBox
        IndividualStepsListBoxLabel    matlab.ui.control.Label
        IndividualStepsListBox         matlab.ui.control.ListBox
        AvailableStepstoRerunDropDownLabel  matlab.ui.control.Label
        AvailableStepstoRerunDropDown  matlab.ui.control.DropDown
        ProcessingStyleButtonGroup     matlab.ui.container.ButtonGroup
        CompleteProcessButton          matlab.ui.control.ToggleButton
        IndividualProcessStepButton    matlab.ui.control.ToggleButton
        RerunProcessStepButton         matlab.ui.control.ToggleButton
        RUNButton                      matlab.ui.control.StateButton
        TestRunCheckBox                matlab.ui.control.CheckBox
        SelectFilesButton              matlab.ui.control.Button
        InputFileorDirectoryEditFieldLabel  matlab.ui.control.Label
        InputFileorDirectoryEditField  matlab.ui.control.EditField
        OutputDirectoryLabel           matlab.ui.control.Label
        OutputDirectoryEditField       matlab.ui.control.EditField
        SelectOutputFolderButton       matlab.ui.control.Button
        SingleFileModeCheckBox         matlab.ui.control.CheckBox
    end


    properties (Access = private)
        PARAMS; % Description
        DRYRUN;
        PROCESS;
    end

    methods (Access = private)
    
        function allowProcessStyle(app)
            app.ProcessingStyleButtonGroup.Visible = 'on';
            app.CompleteProcessButton.Visible = 'on';
            app.IndividualProcessStepButton.Visible = 'on';
            app.RerunProcessStepButton.Visible = 'on';
            app.CompleteProcessButton.Enable=1;
            app.IndividualProcessStepButton.Enable=1;
            app.RerunProcessStepButton.Enable=1;
            app.CompleteProcessButton.Value=0;
            app.IndividualProcessStepButton.Value=0;
            app.RerunProcessStepButton.Value=0;
        end
        
        function denyProcessStyle(app)
            app.ProcessingStyleButtonGroup.Visible = 'off';
            app.CompleteProcessButton.Visible = 'off';
            app.IndividualProcessStepButton.Visible = 'off';
            app.RerunProcessStepButton.Visible = 'off';
            app.CompleteProcessButton.Enable=0;
            app.IndividualProcessStepButton.Enable=0;
            app.RerunProcessStepButton.Enable=0;
            app.CompleteProcessButton.Value=0;
            app.IndividualProcessStepButton.Value=0;
            app.RerunProcessStepButton.Value=0;
        end
        
        function allowParadigms(app)
            paradigms = regexp({ls(fullfile(fileparts(mfilename('fullpath')),'paradigms','*.m'))},'(?<=\_)\w*','match');
            app.ParadigmsListBox.Items = string(paradigms{:});
            app.ParadigmsListBox.ItemsData = string(paradigms{:});
            app.ParadigmsListBox.Visible = 'on';
            app.ParadigmsListBox.Enable = 'on';
            
        end
    
        function denyParadigms(app)
            %app.ParadigmsListBox.Value = {};
            app.ParadigmsListBox.Items = {};
            app.ParadigmsListBox.ItemsData = {};
            app.ParadigmsListBox.Visible = 'off';
            app.ParadigmsListBox.Enable = 'off'; 
            
        end
    
        function allowIndividualSteps(app)
            individualsteps = fieldnames(app.PARAMS);
            app.IndividualStepsListBox.Items = individualsteps;
            app.IndividualStepsListBox.ItemsData = individualsteps;
            app.IndividualStepsListBox.Visible = 'on';
            app.IndividualStepsListBox.Enable = 'on';
            
        end
    
        function denyIndividualSteps(app)
            app.IndividualStepsListBox.Items = {};
            app.IndividualStepsListBox.ItemsData = {};
            app.IndividualStepsListBox.Visible = 'off';
            app.IndividualStepsListBox.Enable = 'off';
        end
    
        function allowRerunSteps(app)
            files=string(ls(fullfile(app.OutputDirectoryEditField.Value,'\*.set')));
            rerunsteps = {files(~contains(files,fieldnames(app.PARAMS)))};
            
%             files=string(ls(fullfile(app.OutputDirectoryEditField.Value,'\*.set')));
%             [startIndex,endIndex]=regexp(files,fieldnames(app.PARAMS));
%             indices=cell2mat([startIndex(~isempty(startIndex)),endIndex(~isempty(endIndex));]);
%             rerunstep = rerunstep(indices(:):indices(:));
            app.AvailableStepstoRerunDropDown.Items = rerunsteps{:};
            app.AvailableStepstoRerunDropDown.ItemsData = rerunsteps{:};
            app.AvailableStepstoRerunDropDown.Visible = 'on';
            app.AvailableStepstoRerunDropDown.Enable = 'on';
        end
        
        function denyRerunSteps(app)
            app.AvailableStepstoRerunDropDown.Items = {};
            app.AvailableStepstoRerunDropDown.ItemsData = {};
            app.AvailableStepstoRerunDropDown.Visible = 'off';
            app.AvailableStepstoRerunDropDown.Enable = 'off';
        end
        
        function denyAll(app)
            denyParadigms(app);
            denyProcessStyle(app);
            denyIndividualSteps(app);
            denyRerunSteps(app);            
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            startFolder = pwd;
            defaultFileName = fullfile(startFolder,'*.set');
            if app.SingleFileModeCheckBox.Value
                [file,folder] = uigetfile('Select a File for Input');
                if file==0
                    figure(app.UIFigure);
                    return;
                end
                fullFileName = fullfile(folder,file);
                app.InputFileorDirectoryEditField.Value = fullFileName;
            else
                [folder] = uigetdir('Select a Folder for Input');
                if folder==0
                    figure(app.UIFigure);
                    return;
                end
                 app.InputFileorDirectoryEditField.Value = folder;
                 
            end
            figure(app.UIFigure);
            if ~isempty(app.OutputDirectoryEditField.Value)
                allowParadigms(app);
            end
        end

        % Value changed function: SingleFileModeCheckBox
        function SingleFileButtonPushed(app, event)
            value = app.SingleFileModeCheckBox.Value;
            if value
                if ~isempty(app.InputFileorDirectoryEditField.Value)
                    app.InputFileorDirectoryEditField.Value = '';
                    denyAll(app);
                    
                end
                app.SelectFilesButton.Text = 'Select Files';
            else
                if ~isempty(app.InputFileorDirectoryEditField.Value)
                    app.InputFileorDirectoryEditField.Value = '';
                    denyAll(app);
                end
                app.SelectFilesButton.Text = 'Select Folder';
            end
        end

        % Button pushed function: SelectOutputFolderButton
        function OutputFolderButtonPushed(app, event)
            [folder] = uigetdir('Select a Folder for Output');
            if folder==0
                figure(app.UIFigure);
                return;
            end
            app.OutputDirectoryEditField.Value = folder;
            figure(app.UIFigure);
            if ~isempty(app.InputFileorDirectoryEditField.Value)
                allowParadigms(app);
            end
                 
        end

        % Value changed function: ParadigmsListBox
        function ParadigmListBoxChanged(app, event)
            value = app.ParadigmsListBox.Value;
            if ~isempty(value)
                %run(fullfile(pwd,'parameters',strcat('parameters_',value)));
                func = str2func(strcat("parameters_",value));
                app.PARAMS=func();
                allowProcessStyle(app);
                app.PROCESS='All';
            else
                denyAll(app);
            end
        end

        % Value changed function: InputFileorDirectoryEditField
        function InputFileorDirectoryEditFieldChanged(app, event)
            value = app.InputFileorDirectoryEditField.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
            end
        end

        % Value changed function: OutputDirectoryEditField
        function OutputDirectoryEditFieldChanged(app, event)
            value = app.OutputDirectoryEditField.Value;
            if ~isempty(value)
                allowParadigms(app);
            else
                denyAll(app);
            end
        end

        % Selection changed function: ProcessingStyleButtonGroup
        function ProcessingStyleButtonGroupChanged(app, event)
            selectedButton = app.ProcessingStyleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Individual Process Step')
                allowIndividualSteps(app);
                denyRerunSteps(app);
                app.PROCESS = 'IndividualStep';
            elseif strcmp(selectedButton.Text, 'Rerun Process Step')
               if ~app.SingleFileModeCheckBox.Value
                   allowRerunSteps(app);
                   denyIndividualSteps(app);
                   app.PROCESS = 'Rerun';
               end
            else
                
                denyIndividualSteps(app);
                denyRerunSteps(app);
                app.PROCESS = 'All';
            end
        end

        % Value changed function: TestRunCheckBox
        function TestRunCheckBoxChanged(app, event)
            value = app.TestRunCheckBox.Value;
            app.DRYRUN = value;
        end

        % Value changed function: RUNButton
        function RunButtonChanged(app, event)
            value = app.RUNButton.Value;
            if value
                if ~isempty(app.PARAMS)
                    func=str2func(strcat("paradigm_",app.ParadigmsListBox.Value));
                
                    if strcmp(app.PROCESS,'Rerun')
                        func(app.InputFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',app.OutputDirectoryEditField.Value,'dryrun',false,'rerunstep',char(app.AvailableStepstoRerunDropDown.Value));
%                         files=string(ls(fullfile(app.OutputDirectoryEditField.Value,'\*.set')));
%                         [startIndex,endIndex]=regexp(files,fieldnames(app.PARAMS));
%                         indices=cell2mat([startIndex(~isempty(startIndex)),endIndex(~isempty(endIndex))]);
%                         rerunstep = files(contains(files,fieldnames(app.PARAMS)));
%                         rerunstep = rerunstep(indices(1):indices(2));
                        func(app.InputFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',app.OutputDirectoryEditField.Value,'dryrun',false,'rerunstep',char(rerunstep));
                    elseif strcmp(app.PROCESS,'IndividualStep')
                        func(app.InputFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',app.OutputDirectoryEditField.Value,'dryrun',false,'individualstep',char(app.IndividualStepsListBox.Value));
                    else
                        func(app.InputFileorDirectoryEditField.Value,app.PARAMS,app.PROCESS,'ext','.set','outputdir',app.OutputDirectoryEditField.Value,'dryrun',false);
                    end
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ParadigmsListBoxLabel = uilabel(app.UIFigure);
            app.ParadigmsListBoxLabel.Text = 'Paradigms';
            app.ParadigmsListBox = uilistbox(app.UIFigure);
            app.ParadigmsListBox.Items = {'{}'};
            app.ParadigmsListBox.ItemsData = {'{}'};
            app.ParadigmsListBox.ValueChangedFcn = createCallbackFcn(app, @ParadigmListBoxChanged, true);
            app.ParadigmsListBox.Enable = 'off';
            app.ParadigmsListBox.Visible = 'off';
            app.ParadigmsListBox.Value = {};
            app.IndividualStepsListBoxLabel = uilabel(app.UIFigure);
            app.IndividualStepsListBoxLabel.Text = 'Individual Steps';
            app.IndividualStepsListBox = uilistbox(app.UIFigure);
            app.IndividualStepsListBox.Items = {'{}'};
            app.IndividualStepsListBox.ItemsData = {'{}'};
            app.IndividualStepsListBox.Enable = 'off';
            app.IndividualStepsListBox.Visible = 'off';
            app.IndividualStepsListBox.Value = '{}';
            app.AvailableStepstoRerunDropDownLabel = uilabel(app.UIFigure);
            app.AvailableStepstoRerunDropDownLabel.Text = 'Available Steps to Rerun';
            app.AvailableStepstoRerunDropDown = uidropdown(app.UIFigure);
            app.AvailableStepstoRerunDropDown.Items = {'[ ]'};
            app.AvailableStepstoRerunDropDown.Enable = 'off';
            app.AvailableStepstoRerunDropDown.Visible = 'off';
            app.AvailableStepstoRerunDropDown.Value = '[ ]';
            app.ProcessingStyleButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessingStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessingStyleButtonGroupChanged, true);
            app.ProcessingStyleButtonGroup.Title = 'Processing Style';
            app.CompleteProcessButton = uitogglebutton(app.ProcessingStyleButtonGroup);
            app.CompleteProcessButton.Enable = 'off';
            app.CompleteProcessButton.Visible = 'off';
            app.CompleteProcessButton.Text = 'Complete Process';
            app.IndividualProcessStepButton = uitogglebutton(app.ProcessingStyleButtonGroup);
            app.IndividualProcessStepButton.Enable = 'off';
            app.IndividualProcessStepButton.Visible = 'off';
            app.IndividualProcessStepButton.Text = 'Individual Process Step';
            app.IndividualProcessStepButton.Value = true;
            app.RerunProcessStepButton = uitogglebutton(app.ProcessingStyleButtonGroup);
            app.RerunProcessStepButton.Enable = 'off';
            app.RerunProcessStepButton.Visible = 'off';
            app.RerunProcessStepButton.Text = 'Rerun Process Step';
            app.RUNButton = uibutton(app.UIFigure, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonChanged, true);
            app.RUNButton.Text = 'RUN';
            app.TestRunCheckBox = uicheckbox(app.UIFigure);
            app.TestRunCheckBox.ValueChangedFcn = createCallbackFcn(app, @TestRunCheckBoxChanged, true);
            app.TestRunCheckBox.Text = 'Test Run?';
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.InputFileorDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.InputFileorDirectoryEditFieldLabel.Text = 'Input File or Directory:';
            app.InputFileorDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.InputFileorDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @InputFileorDirectoryEditFieldChanged, true);
            app.OutputDirectoryLabel = uilabel(app.UIFigure);
            app.OutputDirectoryLabel.Text = 'Output Directory:';
            app.OutputDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.OutputDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @OutputDirectoryEditFieldChanged, true);
            app.SelectOutputFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.SingleFileModeCheckBox = uicheckbox(app.UIFigure);
            app.SingleFileModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleFileButtonPushed, true);
            app.SingleFileModeCheckBox.Text = 'Single File Mode?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = vhtp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end